classdef Reto_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AlertaLabel                    matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Label_7                        matlab.ui.control.Label
        VelocidadLabel_2               matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        EnergiaperdidaLabel            matlab.ui.control.Label
        yLabel                         matlab.ui.control.Label
        xLabel                         matlab.ui.control.Label
        PosicinLabel                   matlab.ui.control.Label
        VelocidadmaximaLabel           matlab.ui.control.Label
        DuracinLabel                   matlab.ui.control.Label
        TiempoActualLabel              matlab.ui.control.Label
        TiempodeinicioLabel            matlab.ui.control.Label
        ResultadosLabel                matlab.ui.control.Label
        SimulacindeFormula1Label       matlab.ui.control.Label
        DatosdeEntradaPanel            matlab.ui.container.Panel
        CorrerSimulacionButton         matlab.ui.control.Button
        Switch                         matlab.ui.control.ToggleSwitch
        CoeficientedefrccionEditField  matlab.ui.control.NumericEditField
        CoeficientedefrccionEditFieldLabel  matlab.ui.control.Label
        MasaEditField                  matlab.ui.control.NumericEditField
        MasaEditFieldLabel             matlab.ui.control.Label
        VelocidadEditField             matlab.ui.control.NumericEditField
        VelocidadLabel                 matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        a = 0.00010262;
        b = -0.040327;
        c = 3.2342;
        d = 261.5886;
        
        f1 = @(x) a * x.^3 + b * x.^2 + c * x + d;
        f2 = @(x) 3 * a * x.^2 + 2 * b * x + c;
        f3 = @(x) 6 * a * x + 2 * b;        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a = 0.00010262;
            app.b = -0.040327;
            app.c = 3.2342;
            app.d = 261.5886;
            
            app.f1 = @(x) app.a * x.^3 + app.b * x.^2 + app.c * x + app.d;
            app.f2 = @(x) 3 * app.a * x.^2 + 2 * app.b * x + app.c;
            app.f3 = @(x) 6 * app.a * x + 2 * app.b;
            
            g_x = [];
            g_y = [];
            g_x_2 = [];
            g_y_2 = [];
            g_x_3 = [];
            g_y_3 = [];
            for i = 10:1:270
                m = app.f2(i);
                tang = @(x) (((-1/m)*(x-i)) + app.f1(i));
                di=0;
                p = 0;
                while p < 20
                        di = di - 0.1;
                        p = sqrt(((i-(i-di))^2)+((app.f1(i)-tang(i-di)))^2);
                end
                if i < 30 
                    g_x = [g_x, i+di];
                    g_y = [g_y, tang(i+di)];
                end
                if (i > 66 && i < 195 )
                    g_x_2 = [g_x_2, i+di];
                    g_y_2 = [g_y_2, tang(i+di)];
                end
                if i > 231
                    g_x_3 = [g_x_3, i+di];
                    g_y_3 = [g_y_3, tang(i+di)];
                end
            end
            
            
            hold(app.UIAxes, "on");
            fplot(app.UIAxes,app.f1,[10,260],"LineWidth",15,"color","#000000");
            fplot(app.UIAxes,app.f1,[10,260],"LineWidth",1,"color","#ffff00","LineStyle","--");            
            plot(app.UIAxes,g_x,g_y, "LineWidth",3,"color","#ff0000")
            plot(app.UIAxes,g_x_2,g_y_2, "LineWidth",3,"color","#ff0000")
            plot(app.UIAxes,g_x_3,g_y_3, "LineWidth",3,"color","#ff0000")
        end

        % Button pushed function: CorrerSimulacionButton
        function CorrerSimulacionButtonPushed(app, event)
            velocidad = app.VelocidadEditField.Value / 10;
            masa = app.MasaEditField.Value;
            coef_friccion = app.CoeficientedefrccionEditField.Value;
            g = 9.81;
            
            tiempo_inicial = datetime('now','TimeZone','local','Format','HH:mm:ss');
            app.Label_3.Text = char(tiempo_inicial);
            
            
            %Maximos y Minimos
            xMax = (- app.b - sqrt(app.b^2 - 3  *app.a *app.c)) / (3 * app.a);
            xMin = (- app.b + sqrt(app.b^2 - 3 * app.a*app.c)) / (3 * app.a);
            
            yMax = app.f1(xMax);
            yMin = app.f1(xMin);
  
            %Radio de la curvatura
            r_curv = @(x) ((1 + (app.f2(x).^2)).^(3/2))/abs(app.f3(x));
            
            %Calculamos la curvatura en el maximo y minimo
            c_max = r_curv(xMax);
            c_min = r_curv(xMin);
            
            %Intervalos de la zona critica
            i_crit = [0];
            for i = 0:1:280
                if (r_curv(i) > 50 && r_curv(i+1) < 50)
                    i_crit = [i_crit, (i+1)];
                elseif (r_curv(i) < 50 && r_curv(i+1) > 50)
                    disp(" ")
                    i_crit = [i_crit, (i+1)];
                end
               
            end
              
            f = app.f1;
            derrapo = false;
            fs = coef_friccion * g * masa;
            for i = 10:0.1:260 
              %r = r_curv(i);
              %Velocidad Maxima
              Velocidad_Max = (sqrt(c_max*coef_friccion*g) / 10);
              if (i >= i_crit(1) && i <= i_crit(2)) || (i >= i_crit(3) && i <= i_crit(4))
                  %if ((coef_friccion*g) - ((velocidad^2)/r) / 10) <= 0
                  %    velocidad = 0;
                  %else
                  %    velocidad = (coef_friccion*g) - ((velocidad^2)/r) / 10;
                  %end
              else
                  %if (velocidad + 1.157) < 490.8
                  %    velocidad = velocidad + 1.157;
                  %end
              end
                  if derrapo == false
                      %if fs < (masa * ((velocidad^2) / r))
                       if velocidad > Velocidad_Max 
                        f = @(x) (app.f2(i)*(x-i)) + app.f1(i);
                        app.AlertaLabel.Visible = true;
                        
                        derrapo = true;
                      end
                  end
              ax = plot(app.UIAxes, i, f(i), ".", "MarkerSize", 30,"Color","red");
              pause(0.001);
              delete(ax)
              
              time = datetime('now','TimeZone','local','Format','HH:mm:ss');
              app.Label_4.Text = char(time);
              
              app.Label_5.Text = char(time - tiempo_inicial);
              app.Label_7.Text = num2str((velocidad));
              app.Label_6.Text = num2str(Velocidad_Max);
              app.Label.Text = num2str(i);
              app.Label_2.Text=num2str(f(i));
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pista')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatosdeEntradaPanel = uipanel(app.UIFigure);
            app.DatosdeEntradaPanel.Title = 'Datos de Entrada';
            app.VelocidadLabel = uilabel(app.DatosdeEntradaPanel);
            app.VelocidadLabel.Text = 'Velocidad:';
            app.VelocidadEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.MasaEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.MasaEditFieldLabel.Text = 'Masa:';
            app.MasaEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.CoeficientedefrccionEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.CoeficientedefrccionEditFieldLabel.Text = 'Coeficiente de frccion';
            app.CoeficientedefrccionEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.Switch = uiswitch(app.DatosdeEntradaPanel, 'toggle');
            app.Switch.Items = {'km/h', 'm/s'};
            app.Switch.Value = 'km/h';
            app.CorrerSimulacionButton = uibutton(app.DatosdeEntradaPanel, 'push');
            app.CorrerSimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @CorrerSimulacionButtonPushed, true);
            app.CorrerSimulacionButton.Text = 'Correr Simulacion';
            app.Panel = uipanel(app.UIFigure);
            app.SimulacindeFormula1Label = uilabel(app.Panel);
            app.SimulacindeFormula1Label.Text = 'Simulación de Formula1';
            app.ResultadosLabel = uilabel(app.Panel);
            app.ResultadosLabel.Text = 'Resultados';
            app.TiempodeinicioLabel = uilabel(app.Panel);
            app.TiempodeinicioLabel.Text = 'Tiempo de inicio:';
            app.TiempoActualLabel = uilabel(app.Panel);
            app.TiempoActualLabel.Text = 'Tiempo Actual:';
            app.DuracinLabel = uilabel(app.Panel);
            app.DuracinLabel.Text = 'Duración:';
            app.VelocidadmaximaLabel = uilabel(app.Panel);
            app.VelocidadmaximaLabel.Text = 'Velocidad maxima: ';
            app.PosicinLabel = uilabel(app.Panel);
            app.PosicinLabel.Text = 'Posición';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x:';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y:';
            app.EnergiaperdidaLabel = uilabel(app.Panel);
            app.EnergiaperdidaLabel.Text = 'Energia perdida:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.VelocidadLabel_2 = uilabel(app.Panel);
            app.VelocidadLabel_2.Text = 'Velocidad:';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.AlertaLabel = uilabel(app.UIFigure);
            app.AlertaLabel.Visible = 'off';
            app.AlertaLabel.Text = 'Alerta!!!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reto_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end